classdef InductorDesign_ac_dc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        murEditField                   matlab.ui.control.NumericEditField
        murEditFieldLabel              matlab.ui.control.Label
        lemmEditField                  matlab.ui.control.NumericEditField
        lemmEditFieldLabel             matlab.ui.control.Label
        Volumemm3EditField_2           matlab.ui.control.NumericEditField
        Volumemm3EditField_2Label      matlab.ui.control.Label
        CoreNameEditField              matlab.ui.control.EditField
        CoreNameEditFieldLabel         matlab.ui.control.Label
        InductorDesigntoolLabel        matlab.ui.control.Label
        TotalLossWEditField            matlab.ui.control.NumericEditField
        TotalLossWEditFieldLabel       matlab.ui.control.Label
        CopperLossWEditField           matlab.ui.control.NumericEditField
        CopperLossWEditFieldLabel      matlab.ui.control.Label
        WindowUtilizationEditField     matlab.ui.control.NumericEditField
        WindowUtilizationEditFieldLabel  matlab.ui.control.Label
        GapLossWEditField              matlab.ui.control.NumericEditField
        GapLossWEditFieldLabel         matlab.ui.control.Label
        AirGapmmEditField              matlab.ui.control.NumericEditField
        AirGapmmEditFieldLabel         matlab.ui.control.Label
        CoreLossWEditField             matlab.ui.control.NumericEditField
        CoreLossWEditFieldLabel        matlab.ui.control.Label
        SelectCoreDropDown             matlab.ui.control.DropDown
        SelectCoreDropDownLabel        matlab.ui.control.Label
        TypeofInductorDropDown         matlab.ui.control.DropDown
        TypeofInductorDropDownLabel    matlab.ui.control.Label
        GmmEditField                   matlab.ui.control.NumericEditField
        GmmEditFieldLabel              matlab.ui.control.Label
        DmmEditField                   matlab.ui.control.NumericEditField
        DmmEditFieldLabel              matlab.ui.control.Label
        BmmEditField                   matlab.ui.control.NumericEditField
        BmmEditFieldLabel              matlab.ui.control.Label
        EmmEditField                   matlab.ui.control.NumericEditField
        EmmEditFieldLabel              matlab.ui.control.Label
        CmmEditField                   matlab.ui.control.NumericEditField
        CmmEditFieldLabel              matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        AmmEditField                   matlab.ui.control.NumericEditField
        AmmEditFieldLabel              matlab.ui.control.Label
        IfPossibleEditField            matlab.ui.control.EditField
        IfPossibleEditFieldLabel       matlab.ui.control.Label
        TemperatureriseDegCEditField   matlab.ui.control.NumericEditField
        TemperatureriseDegCEditFieldLabel  matlab.ui.control.Label
        Volumeofcoremm3EditField       matlab.ui.control.NumericEditField
        Volumeofcoremm3EditFieldLabel  matlab.ui.control.Label
        MaxFluxDensityTEditField       matlab.ui.control.NumericEditField
        MaxFluxDensityTEditFieldLabel  matlab.ui.control.Label
        InductanceObtainedHEditField   matlab.ui.control.NumericEditField
        InductanceObtainedHEditFieldLabel  matlab.ui.control.Label
        NumberofStackEditField         matlab.ui.control.NumericEditField
        NumberofStackEditFieldLabel    matlab.ui.control.Label
        NumberofTurnsEditField         matlab.ui.control.NumericEditField
        NumberofTurnsEditFieldLabel    matlab.ui.control.Label
        GoButton                       matlab.ui.control.Button
        TypeofCoreDropDown             matlab.ui.control.DropDown
        TypeofCoreDropDownLabel        matlab.ui.control.Label
        InductorRipplecurrentAEditField  matlab.ui.control.NumericEditField
        InductorRipplecurrentAEditFieldLabel  matlab.ui.control.Label
        FrequencyofRipplecurrentHzEditField  matlab.ui.control.NumericEditField
        FrequencyofRipplecurrentHzEditFieldLabel  matlab.ui.control.Label
        InductorRmsCurrentAEditField   matlab.ui.control.NumericEditField
        InductorRmsCurrentAEditFieldLabel  matlab.ui.control.Label
        InductanceHEditField           matlab.ui.control.NumericEditField
        InductanceHEditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            Curr_Ind_rms= app.InductorRmsCurrentAEditField.Value;% Inductor Rms Current, Amp
            fs= app.FrequencyofRipplecurrentHzEditField.Value;% Frequency seen by inductor,Hz
            Del_Io= app.InductorRipplecurrentAEditField.Value;%Curr_Ind_rms;% Ripple current of Inductor, Amp
            L_Value= app.InductanceHEditField.Value;% Inductance required, H

            Type_of_TF=app.TypeofInductorDropDown.Value;

            C.Core_name=app.CoreNameEditField.Value;
            C.mur = app.murEditField.Value; % Relative Permeability for ungapped core
            C.le = app.lemmEditField.Value; % mm % Effective Flux path length
            C.Volume_onestack = app.Volumemm3EditField_2.Value; % mm^3 % Permeance
            C.Core_A = app.AmmEditField.Value; % mm % Core Length
            C.Core_B = app.BmmEditField.Value; % mm % Core Window to Window end end distance
            C.Core_C = app.CmmEditField.Value; % mm % Core Breadth *2
            C.Core_D = app.DmmEditField.Value; % mm % Core Width
            C.Core_E = app.EmmEditField.Value; % mm % Core Window to Window in in distance
            C.Core_G = app.GmmEditField.Value; % mm % Core Window Width

            Core_name=app.SelectCoreDropDown.Value;
            
            if(strcmp(Core_name,'Enter your own')) 
                check=Curr_Ind_rms<0 || Del_Io<0 || L_Value<0 || fs<0 || C.mur<0 || C.le<0 || C.Volume_onestack<0 || C.Core_A<=0 || C.Core_B<=0 || C.Core_C<=0 || C.Core_D<=0 || C.Core_E<=0 || C.Core_G<=0;
            else
                check=Curr_Ind_rms<0 || Del_Io<0 || L_Value<0 || fs<0;
            end

            if(check)
                warndlg('Input must be a greater than zero...');

            else
                if(strcmp(Core_name,'E-42/21/15'))
                    C.Core_name='E-42/21/15';
                    C.mur = 1778; % Relative Permeability for ungapped core
                    C.le = 97; % mm % Effective Flux path length
                    C.Volume_onestack = 17300; % mm^3 % Permeance
                    C.Core_A = 42; % mm % Core Length
                    C.Core_B = 29.5; % mm % Core Window to Window end end distance
                    C.Core_C = 21*2; % mm % Core Breadth *2
                    C.Core_D = 15; % mm % Core Width
                    C.Core_E = 12.2; % mm % Core Window to Window in in distance
                    C.Core_G = 29.6; % mm % Core Window Width
                    
                
                elseif(strcmp(Core_name,'E-20/10/6'))
                    C.Core_name='E-20/10/6';
                    C.mur = 1690; % Relative Permeability for ungapped core
                    C.le = 46.3; % mm % Effective Flux path length
                    C.Volume_onestack = 1490; % mm^3 % Permeance
                    C.Core_A = 20; % mm % Core Length
                    C.Core_B = 14.1; % mm % Core Window to Window end end distance
                    C.Core_C = 20; % mm % Core Breadth *2
                    C.Core_D = 6; % mm % Core Width
                    C.Core_E = 5.9; % mm % Core Window to Window in in distance
                    C.Core_G = 14; % mm % Core Window Width
                
                elseif(strcmp(Core_name,'E-30/15/7'))
                    C.Core_name='E-30/15/7';
                    C.mur = 1690; % Relative Permeability for ungapped core
                    C.le = 67; % mm % Effective Flux path length
                    C.Volume_onestack = 4000; % mm^3 % Permeance
                    C.Core_A = 30; % mm % Core Length
                    C.Core_B = 19.5; % mm % Core Window to Window end end distance
                    C.Core_C = 30; % mm % Core Breadth *2
                    C.Core_D = 7; % mm % Core Width
                    C.Core_E = 7.2; % mm % Core Window to Window in in distance
                    C.Core_G = 19.4; % mm % Core Window Width
                
                elseif(strcmp(Core_name,'E-55/28/21'))
                    C.Core_name='E-55/28/21';
                    C.mur = 1780; % Relative Permeability for ungapped core
                    C.le = 124; % mm % Effective Flux path length
                    C.Volume_onestack = 43900; % mm^3 % Permeance
                    C.Core_A = 55; % mm % Core Length
                    C.Core_B = 37.5; % mm % Core Window to Window end end distance
                    C.Core_C = 56; % mm % Core Breadth *2
                    C.Core_D = 21; % mm % Core Width
                    C.Core_E = 17.2; % mm % Core Window to Window in in distance
                    C.Core_G = 37; % mm % Core Window Width   
                else
                    C.Core_name=app.CoreNameEditField.Value;
                    C.mur = app.murEditField.Value; % Relative Permeability for ungapped core
                    C.le = app.lemmEditField.Value; % mm % Effective Flux path length
                    C.Volume_onestack = app.Volumemm3EditField_2.Value; % mm^3 % Permeance
                    C.Core_A = app.AmmEditField.Value; % mm % Core Length
                    C.Core_B = app.BmmEditField.Value; % mm % Core Window to Window end end distance
                    C.Core_C = app.CmmEditField.Value; % mm % Core Breadth *2
                    C.Core_D = app.DmmEditField.Value; % mm % Core Width
                    C.Core_E = app.EmmEditField.Value; % mm % Core Window to Window in in distance
                    C.Core_G = app.GmmEditField.Value; % mm % Core Window Width
                end

               %% Justified Assumptions
                ifpossible=0;
                Kf= 4.44;% Waveform factor
                J= 4;% Current density, A/mm2
                K_wu= 0.4;% Window utilization factor
                rho_cu= 1.72e-8; % Copper resistivity
                mu_cu=0.9999; % Relative Copper permeability
                mu0 = 4*pi*1e-7; % H/m % Permeability of Free Space
                Kpf = 1.28; % packing factor, typically taken 1.25 to 1.28 depending on number of strands
                
            
                Bmax_allowed=0.2;
                if(L_Value<1e-6)
                    L_error=0.1e-6;
                else
                    L_error=0.5e-6;
                end
                Max_temp_rise_allowed=120;
            
                %% Conductor cals 
                A_Copp = Curr_Ind_rms / J;% Required cross section area for copper, mm2
                Skin_Depth = 66/sqrt(fs); % Skin depth for AC current, mm
                
                
                if(strcmp(Type_of_TF,'DC'))
                    Num_Strands_cu=4;
                    Outer_D_Cu=sqrt(A_Copp*4/Num_Strands_cu/pi);
                    Kpf=1;
                else    
                    Outer_D_Cu = Skin_Depth*2; % Outer dia for cop[er strand, mm
                    Num_Strands_cu = ceil(A_Copp/(pi*Outer_D_Cu^2/4)); % Number of strands for copper required
                end
                
                Coat_t = 0.07*Outer_D_Cu ; % Enamel / insulation coating, mm, 7% of Copper outer dia
                Outer_dia_Litz = Kpf*sqrt(Num_Strands_cu)*(Coat_t+Outer_D_Cu) ; % Outer diameter of litz wire, mm
                A_Litz= pi*Outer_dia_Litz^2/4; % Area of litz wire, mm2
            
                A_Copp_Cor=pi*Num_Strands_cu*Outer_D_Cu^2/4; % Corrected area of copper with selected strands, mm2

                
                Num_Turns_per_layers = 0.7 * C.Core_G / Outer_dia_Litz;
                    
            
            %% In our control 
                for Num_stack=1:4 % Number of stacks
                    for N_t=1:1  % Number of turns
                        %%
            
                        Num_layers =ceil(N_t/Num_Turns_per_layers);
                        alpha=1000/Skin_Depth;%real(sqrt(i*2*pi*fs*mu0*eta/rho_cu))
                        h=Outer_D_Cu/1000;
                        m=Num_layers;
                        K=real(alpha*h*coth(alpha*h))+(m*m-1)*real(2*alpha*h*tanh(alpha*h*0.5))/3;

                        %K=Compute(Outer_D_Cu,Num_layers,Skin_Depth);
                        
                        A_Wind_Req=A_Litz*N_t; %Required Window Area mm2
            
                        A_Cu_H=2*(N_t*Outer_dia_Litz*(C.Core_E+2*Outer_dia_Litz+Num_stack*C.Core_D+2*Outer_dia_Litz)); % Cross section area where copper is placed over Core, mm2


                        %% Core Parameters
                        % Core dimensions as per image
                    
                        Ae= C.Core_D*C.Core_E*Num_stack * 0.95; % Core effective area, mm2, 5% margin considered
                        Acw= (C.Core_B-C.Core_E)*C.Core_G/2; % Window area
                        ln= 2*(C.Core_B+Num_stack*C.Core_D); % Mean per turn length
                        K_core= 2*1e5; %Relative_core_loss_coeff, W/m3
                        A_Surf= ((C.Core_A+C.Core_C+C.Core_G)*C.Core_D*Num_stack+(C.Core_A*C.Core_C)-(C.Core_B-C.Core_E)*C.Core_G)*2; % Core surface area mm2
                        Acw_Avai=Acw*K_wu; % Effective window area available considering 2.5 as winding factor, mm
            
            
                        %% Check for Window area condition 


                        if(A_Wind_Req<Acw_Avai)
                           
                            %set Air gap to be allowed maximum
                            lg=0;
                            while(lg<(0.1*sqrt(Ae)))% Air gap % should be less than 0.1*sqrt(Ae)


            %% Inductance Computation
                                mur_g=C.mur/(C.mur*lg*0.5/C.le+1);
                                if(lg>0)
                                    Frng_Fctr=(1+lg/sqrt(Ae)*log(2*C.Core_G/lg)); % Fringing factor
                                else
                                    Frng_Fctr=1;
                                end
                                %L_Value_Af_frng=L_Value/Frng_Fctr;  % Corrected inductance value after taking into account fringing
                                L_Obtained=1e-6*mur_g*mu0*Ae*N_t*N_t*1e3/(lg*mur_g+C.le)*Frng_Fctr; % Obtained Inductance from selected core design in H
                                Bmax=1.5*Curr_Ind_rms*sqrt(2)*L_Obtained/(Ae*N_t*1e-6);% maximum flux density
                                if(Bmax<Bmax_allowed && abs(L_Value-L_Obtained)<L_error)
                                    R_DC=rho_cu*ln*N_t*1e-3/(A_Copp_Cor*1e-6); % DC Resistance, ohm
                                    R_AC=R_DC*K/Num_Strands_cu; % AC resistance, ohm
                                    
                                    Cu_Loss_Ac=Curr_Ind_rms^2*R_AC; %  AC Copper Losses, Watt
                                    Cu_Loss_Dc=Curr_Ind_rms^2*R_DC; %  AC Copper Losses, Watt
                                    Core_loss=C.Volume_onestack*Num_stack*K_core*1e-9; % Core Loss Watt
                                    Gap_Loss=0.155*C.Core_E*lg*fs*(Bmax/1.5)^2*1e-2; % gap Loss in Watt
                                    Total_loss=Cu_Loss_Dc+Cu_Loss_Ac+Core_loss+Gap_Loss; % Total Loss in watt
                                    
                                    
                                    Temp_Rise_core=(Core_loss*1000/(A_Surf/100))^0.833; % Core Temperature rise in Deg Celcius
                                    Temp_Rise_cu=((Cu_Loss_Dc+Cu_Loss_Ac)*1000/(A_Cu_H/100))^0.833; % Copper Temperature rise in Deg Celcius
                                    Temp_Rise_Total=(Total_loss*1000/((A_Cu_H+A_Surf)/100))^0.833; % Total temperature rise
                                    
                                    Core_Volume=C.Volume_onestack*Num_stack;
                                    Core=C.Core_name;
                                    if(Temp_Rise_Total<Max_temp_rise_allowed)
                                        ifpossible=1;
                                        app.IfPossibleEditField='Possible';
                                        break;
                                    end
                                else
                                    %R_DC=0; % DC Resistance, ohm
                                    %R_AC=0; % AC resistance, ohm
                                    
                                    Cu_Loss_Ac=0; %  AC Copper Losses, Watt
                                    Cu_Loss_Dc=0; %  AC Copper Losses, Watt
                                    Core_loss=0; % Core Loss Watt
                                    Gap_Loss=0; % gap Loss in Watt
                                    Total_loss=0; % Total Loss in watt
                                    
                                    
                                    %Temp_Rise_core=0; % Core Temperature rise in Deg Celcius
                                    %Temp_Rise_cu=0; % Copper Temperature rise in Deg Celcius
                                    Temp_Rise_Total=0; % Total temperature rise
                                    
                                    Core_Volume=0;
                                    %Core='Not Possible';
                                end
                                lg= lg+0.05; 
                            end
                                 
                        else
                            ifpossible=0;
                            break;
                        end
                        if(ifpossible)
                            break;
                        end
                    
                    % Reference: https://ietresearch.onlinelibrary.wiley.com/doi/epdf/10.1049/iet-cds.2016.0410
                    
                    end
            
                    if(ifpossible)
                        break;
                    end
                end
                if(~ifpossible)
                    app.IfPossibleEditField.Value=' Not Possible';
                    app.NumberofStackEditField.Enable="off";
                    app.NumberofTurnsEditField.Enable="off";
                    app.Volumeofcoremm3EditField.Enable="off";
                    app.CoreLossWEditField.Enable="off";
                    app.GapLossWEditField.Enable="off";
                    app.TemperatureriseDegCEditField.Enable="off";
                    app.AirGapmmEditField.Enable="off";
                    app.WindowUtilizationEditField.Enable="off";
                    app.CopperLossWEditField.Enable="off";
                    app.TotalLossWEditField.Enable="off";
                    app.InductanceObtainedHEditField.Enable="off";
                    app.MaxFluxDensityTEditField.Enable="off";
                else
                    app.IfPossibleEditField.Value='Possible';

                    app.NumberofStackEditField.Enable="on";
                    app.NumberofTurnsEditField.Enable="on";
                    app.Volumeofcoremm3EditField.Enable="on";
                    app.CoreLossWEditField.Enable="on";
                    app.GapLossWEditField.Enable="on";
                    app.TemperatureriseDegCEditField.Enable="on";
                    app.AirGapmmEditField.Enable="on";
                    app.WindowUtilizationEditField.Enable="on";
                    app.CopperLossWEditField.Enable="on";
                    app.TotalLossWEditField.Enable="on";
                    app.InductanceObtainedHEditField.Enable="on";
                    app.MaxFluxDensityTEditField.Enable="on";


                    app.InductanceObtainedHEditField.Value=L_Obtained;
                    app.MaxFluxDensityTEditField.Value=Bmax;
                    app.NumberofStackEditField.Value=Num_stack;
                    app.NumberofTurnsEditField.Value=N_t;
                    app.Volumeofcoremm3EditField.Value=Core_Volume;
                    app.CoreLossWEditField.Value=Core_loss;
                    app.GapLossWEditField.Value=Gap_Loss;
                    app.TemperatureriseDegCEditField.Value=Temp_Rise_Total;
                    app.AirGapmmEditField.Value=lg;
                    app.WindowUtilizationEditField.Value=A_Wind_Req/Acw_Avai;
                    app.CopperLossWEditField.Value=Cu_Loss_Dc+Cu_Loss_Ac;
                    app.TotalLossWEditField.Value=Total_loss;


                end


            end    

        end

        % Value changed function: SelectCoreDropDown
        function SelectCoreDropDownValueChanged(app, event)
            Core_name = app.SelectCoreDropDown.Value;
                        
            app.AmmEditField.Enable="off";
            app.BmmEditField.Enable="off"; % Relative Permeability
            app.CmmEditField.Enable="off"; % mm^2 %  Effective area of the core
            app.DmmEditField.Enable="off"; % mm % Effective Flux path length
            app.EmmEditField.Enable="off"; % nH/n^2 %  Permeance
            app.GmmEditField.Enable="off"; % mm^3 % Volume of core
            app.Volumemm3EditField_2.Enable="off"; % mm^2 % Window area
            app.lemmEditField.Enable="off"; % mm % Mean length per turn
            app.murEditField.Enable="off"; % mm % Core Width 
            if(strcmp(Core_name,'Enter your own'))
                app.AmmEditField.Enable="on";
                app.BmmEditField.Enable="on"; % Relative Permeability
                app.CmmEditField.Enable="on"; % mm^2 %  Effective area of the core
                app.DmmEditField.Enable="on"; % mm % Effective Flux path length
                app.EmmEditField.Enable="on"; % nH/n^2 %  Permeance
                app.GmmEditField.Enable="on"; % mm^3 % Volume of core
                app.Volumemm3EditField_2.Enable="on"; % mm^2 % Window area
                app.lemmEditField.Enable="on"; % mm % Mean length per turn
                app.murEditField.Enable="on"; % mm % Core Width 

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InductanceHEditFieldLabel = uilabel(app.UIFigure);
            app.InductanceHEditFieldLabel.Text = 'Inductance (H)';
            app.InductanceHEditField = uieditfield(app.UIFigure, 'numeric');
            app.InductorRmsCurrentAEditFieldLabel = uilabel(app.UIFigure);
            app.InductorRmsCurrentAEditFieldLabel.Text = 'Inductor Rms Current (A)';
            app.InductorRmsCurrentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyofRipplecurrentHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyofRipplecurrentHzEditFieldLabel.Text = 'Frequency of Ripple current(Hz)';
            app.FrequencyofRipplecurrentHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.InductorRipplecurrentAEditFieldLabel = uilabel(app.UIFigure);
            app.InductorRipplecurrentAEditFieldLabel.Text = 'Inductor Ripple current (A)';
            app.InductorRipplecurrentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.TypeofCoreDropDownLabel = uilabel(app.UIFigure);
            app.TypeofCoreDropDownLabel.Text = 'Type of Core';
            app.TypeofCoreDropDown = uidropdown(app.UIFigure);
            app.TypeofCoreDropDown.Items = {'EE', 'PQ'};
            app.TypeofCoreDropDown.Value = 'EE';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.NumberofTurnsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofTurnsEditFieldLabel.Text = 'Number of Turns';
            app.NumberofTurnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofStackEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStackEditFieldLabel.Text = 'Number of Stack';
            app.NumberofStackEditField = uieditfield(app.UIFigure, 'numeric');
            app.InductanceObtainedHEditFieldLabel = uilabel(app.UIFigure);
            app.InductanceObtainedHEditFieldLabel.Text = 'Inductance Obtained (H)';
            app.InductanceObtainedHEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxFluxDensityTEditFieldLabel = uilabel(app.UIFigure);
            app.MaxFluxDensityTEditFieldLabel.Text = 'Max Flux Density (T)';
            app.MaxFluxDensityTEditField = uieditfield(app.UIFigure, 'numeric');
            app.Volumeofcoremm3EditFieldLabel = uilabel(app.UIFigure);
            app.Volumeofcoremm3EditFieldLabel.Text = 'Volume  of core(mm3)';
            app.Volumeofcoremm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureriseDegCEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureriseDegCEditFieldLabel.Text = 'Temperature rise (Deg C)';
            app.TemperatureriseDegCEditField = uieditfield(app.UIFigure, 'numeric');
            app.IfPossibleEditFieldLabel = uilabel(app.UIFigure);
            app.IfPossibleEditFieldLabel.Text = 'If Possible';
            app.IfPossibleEditField = uieditfield(app.UIFigure, 'text');
            app.AmmEditFieldLabel = uilabel(app.UIFigure);
            app.AmmEditFieldLabel.Text = 'A (mm)';
            app.AmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'EEInductor.png');
            app.CmmEditFieldLabel = uilabel(app.UIFigure);
            app.CmmEditFieldLabel.Text = 'C (mm)';
            app.CmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.EmmEditFieldLabel = uilabel(app.UIFigure);
            app.EmmEditFieldLabel.Text = 'E (mm)';
            app.EmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BmmEditFieldLabel = uilabel(app.UIFigure);
            app.BmmEditFieldLabel.Text = 'B (mm)';
            app.BmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DmmEditFieldLabel = uilabel(app.UIFigure);
            app.DmmEditFieldLabel.Text = 'D (mm)';
            app.DmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GmmEditFieldLabel = uilabel(app.UIFigure);
            app.GmmEditFieldLabel.Text = 'G (mm)';
            app.GmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TypeofInductorDropDownLabel = uilabel(app.UIFigure);
            app.TypeofInductorDropDownLabel.Text = 'Type of Inductor';
            app.TypeofInductorDropDown = uidropdown(app.UIFigure);
            app.TypeofInductorDropDown.Items = {'AC', 'DC'};
            app.TypeofInductorDropDown.Value = 'AC';
            app.SelectCoreDropDownLabel = uilabel(app.UIFigure);
            app.SelectCoreDropDownLabel.Text = 'Select Core';
            app.SelectCoreDropDown = uidropdown(app.UIFigure);
            app.SelectCoreDropDown.Items = {'Enter your own', 'E-20/10/6', 'E-30/15/7', 'E-42/21/15', 'E-55/28/21'};
            app.SelectCoreDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCoreDropDownValueChanged, true);
            app.SelectCoreDropDown.Value = 'Enter your own';
            app.CoreLossWEditFieldLabel = uilabel(app.UIFigure);
            app.CoreLossWEditFieldLabel.Text = 'Core Loss (W)';
            app.CoreLossWEditField = uieditfield(app.UIFigure, 'numeric');
            app.AirGapmmEditFieldLabel = uilabel(app.UIFigure);
            app.AirGapmmEditFieldLabel.Text = 'Air Gap (mm)';
            app.AirGapmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GapLossWEditFieldLabel = uilabel(app.UIFigure);
            app.GapLossWEditFieldLabel.Text = 'Gap Loss (W)';
            app.GapLossWEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowUtilizationEditFieldLabel = uilabel(app.UIFigure);
            app.WindowUtilizationEditFieldLabel.Text = 'Window Utilization';
            app.WindowUtilizationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CopperLossWEditFieldLabel = uilabel(app.UIFigure);
            app.CopperLossWEditFieldLabel.Text = 'Copper Loss (W)';
            app.CopperLossWEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalLossWEditFieldLabel = uilabel(app.UIFigure);
            app.TotalLossWEditFieldLabel.Text = 'Total Loss (W)';
            app.TotalLossWEditField = uieditfield(app.UIFigure, 'numeric');
            app.InductorDesigntoolLabel = uilabel(app.UIFigure);
            app.InductorDesigntoolLabel.Text = 'Inductor Design tool';
            app.CoreNameEditFieldLabel = uilabel(app.UIFigure);
            app.CoreNameEditFieldLabel.Text = 'Core Name';
            app.CoreNameEditField = uieditfield(app.UIFigure, 'text');
            app.Volumemm3EditField_2Label = uilabel(app.UIFigure);
            app.Volumemm3EditField_2Label.Text = 'Volume (mm3)';
            app.Volumemm3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.lemmEditFieldLabel = uilabel(app.UIFigure);
            app.lemmEditFieldLabel.Text = 'le (mm)';
            app.lemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.murEditFieldLabel = uilabel(app.UIFigure);
            app.murEditFieldLabel.Text = 'mur';
            app.murEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InductorDesign_ac_dc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end